classdef motor_opti_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AvailableLamp                   matlab.ui.control.Lamp
        AvailableLampLabel              matlab.ui.control.Label
        RunCalculatorOptimiserButton    matlab.ui.control.Button
        ResultsViewerPanel              matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        CalculationsOptimisationTab     matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        GraphsTab                       matlab.ui.container.Tab
        fminconLabel                    matlab.ui.control.Label
        GeneticAlgoLabel                matlab.ui.control.Label
        fmin_HaltButton                 matlab.ui.control.Button
        ga_HaltButton                   matlab.ui.control.Button
        fmin_UIAxes                     matlab.ui.control.UIAxes
        ga_UIAxes                       matlab.ui.control.UIAxes
        LogTab                          matlab.ui.container.Tab
        ClearButton                     matlab.ui.control.Button
        GAlogTextArea                   matlab.ui.control.TextArea
        LoadMotionProfileTab            matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        AllTab                          matlab.ui.container.Tab
        actual_mp_UIAxes                matlab.ui.control.UIAxes
        ref_mp_UIAxes                   matlab.ui.control.UIAxes
        PositonTab                      matlab.ui.container.Tab
        actual_pos_UIAxes               matlab.ui.control.UIAxes
        ref_pos_UIAxes                  matlab.ui.control.UIAxes
        VelocityTab                     matlab.ui.container.Tab
        actual_vel_UIAxes               matlab.ui.control.UIAxes
        ref_vel_UIAxes                  matlab.ui.control.UIAxes
        AccelerationTab                 matlab.ui.container.Tab
        actual_acc_UIAxes               matlab.ui.control.UIAxes
        ref_acc_UIAxes                  matlab.ui.control.UIAxes
        ErrorTab                        matlab.ui.container.Tab
        acc_error_UIAxes                matlab.ui.control.UIAxes
        vel_error_UIAxes                matlab.ui.control.UIAxes
        pos_error_UIAxes                matlab.ui.control.UIAxes
        EnergyUsageTab                  matlab.ui.container.Tab
        JLabel                          matlab.ui.control.Label
        ActualEnergyUsageEditField      matlab.ui.control.NumericEditField
        ActualEnergyUsageEditFieldLabel  matlab.ui.control.Label
        actual_energy_UIAxes            matlab.ui.control.UIAxes
        MotorCharacteristicsTab         matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        CurrentandTorqueTab             matlab.ui.container.Tab
        trq_UIAxes                      matlab.ui.control.UIAxes
        curr_UIAxes                     matlab.ui.control.UIAxes
        VoltageTab                      matlab.ui.container.Tab
        volt_UIAxes                     matlab.ui.control.UIAxes
        RunSimulationButton             matlab.ui.control.Button
        StopSimulationButton            matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ConveyorTab                     matlab.ui.container.Tab
        ConveyorParametersPanel         matlab.ui.container.Panel
        Label_23                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        NmradsLabel                     matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        kgLabel_4                       matlab.ui.control.Label
        kgLabel_3                       matlab.ui.control.Label
        kgLabel_2                       matlab.ui.control.Label
        kgLabel                         matlab.ui.control.Label
        degLabel                        matlab.ui.control.Label
        BedBeltfrictionEditField        matlab.ui.control.NumericEditField
        BedBeltfrictionEditFieldLabel   matlab.ui.control.Label
        ShaftdampingEditField           matlab.ui.control.NumericEditField
        ShaftdampingEditFieldLabel      matlab.ui.control.Label
        ShaftradiusEditField            matlab.ui.control.NumericEditField
        ShaftradiusEditFieldLabel       matlab.ui.control.Label
        PulleyradiusEditField           matlab.ui.control.NumericEditField
        PulleyradiusEditFieldLabel      matlab.ui.control.Label
        LoadmassEditField               matlab.ui.control.NumericEditField
        LoadmassEditFieldLabel          matlab.ui.control.Label
        ShaftmassEditField              matlab.ui.control.NumericEditField
        ShaftmassEditFieldLabel         matlab.ui.control.Label
        BeltmassEditField               matlab.ui.control.NumericEditField
        BeltmassEditFieldLabel          matlab.ui.control.Label
        PulleymassEditField             matlab.ui.control.NumericEditField
        PulleymassEditFieldLabel        matlab.ui.control.Label
        InclinationAngleEditField       matlab.ui.control.NumericEditField
        InclinationAngleEditFieldLabel  matlab.ui.control.Label
        GearEfficiencyEditField         matlab.ui.control.NumericEditField
        GearEfficiencyEditFieldLabel    matlab.ui.control.Label
        GearRatioEditField              matlab.ui.control.NumericEditField
        GearRatioEditFieldLabel         matlab.ui.control.Label
        LoadConveyorConfigButton        matlab.ui.control.Button
        SaveConveyorConfigButton        matlab.ui.control.Button
        MotorTab                        matlab.ui.container.Tab
        MotorParametersPanel            matlab.ui.container.Panel
        Label_22                        matlab.ui.control.Label
        Kgmsup2supLabel                 matlab.ui.control.Label
        NmLabel                         matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        HLabel                          matlab.ui.control.Label
        OhmsLabel                       matlab.ui.control.Label
        NmALabel                        matlab.ui.control.Label
        NmsradLabel                     matlab.ui.control.Label
        InputVoltageEditField           matlab.ui.control.NumericEditField
        InputVoltageEditFieldLabel      matlab.ui.control.Label
        CoilInductanceEditField         matlab.ui.control.NumericEditField
        CoilInductanceEditFieldLabel    matlab.ui.control.Label
        CoilResistanceEditField         matlab.ui.control.NumericEditField
        CoilResistanceEditFieldLabel    matlab.ui.control.Label
        MotorConstantKeEditField        matlab.ui.control.NumericEditField
        MotorConstantKeEditFieldLabel   matlab.ui.control.Label
        RotordampingEditField           matlab.ui.control.NumericEditField
        RotordampingEditFieldLabel      matlab.ui.control.Label
        RotorinertiaEditField           matlab.ui.control.NumericEditField
        RotorinertiaEditFieldLabel      matlab.ui.control.Label
        MaxContinuousTorqueEditField    matlab.ui.control.NumericEditField
        MaxContinuousTorqueEditFieldLabel  matlab.ui.control.Label
        MaxContinuousCurrentEditField   matlab.ui.control.NumericEditField
        MaxContinuousCurrentEditFieldLabel  matlab.ui.control.Label
        UseOperatingLimitsEditField     matlab.ui.control.NumericEditField
        UseOperatingLimitsEditFieldLabel  matlab.ui.control.Label
        LoadMotorConfigButton           matlab.ui.control.Button
        SaveMotorConfigButton           matlab.ui.control.Button
        MotionTab                       matlab.ui.container.Tab
        MotionParametersPanel           matlab.ui.container.Panel
        Label_21                        matlab.ui.control.Label
        sLabel_4                        matlab.ui.control.Label
        sLabel_3                        matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        radssup2supLabel_2              matlab.ui.control.Label
        radssup2supLabel                matlab.ui.control.Label
        radsLabel                       matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        radLabel                        matlab.ui.control.Label
        UseParamsDropDown               matlab.ui.control.DropDown
        UseParamsDropDownLabel          matlab.ui.control.Label
        DecelerationTimeEditField       matlab.ui.control.NumericEditField
        DecelerationTimeEditFieldLabel  matlab.ui.control.Label
        AccelerationTimeEditField       matlab.ui.control.NumericEditField
        AccelerationTimeEditFieldLabel  matlab.ui.control.Label
        OptimiseParamsDropDown          matlab.ui.control.DropDown
        USEPARAMSLabel                  matlab.ui.control.Label
        StartingTimeEditField           matlab.ui.control.NumericEditField
        StartingTimeEditFieldLabel      matlab.ui.control.Label
        MaxDecelerationEditField        matlab.ui.control.NumericEditField
        MaxDecelerationEditFieldLabel   matlab.ui.control.Label
        MaxAccelerationEditField        matlab.ui.control.NumericEditField
        MaxAccelerationEditFieldLabel   matlab.ui.control.Label
        MaxVelocityEditField            matlab.ui.control.NumericEditField
        MaxVelocityEditFieldLabel       matlab.ui.control.Label
        SlewRatekEditField              matlab.ui.control.NumericEditField
        SlewRatekEditFieldLabel         matlab.ui.control.Label
        TotalTimeEditField              matlab.ui.control.NumericEditField
        TotalTimeEditFieldLabel         matlab.ui.control.Label
        TrajectoryLengthEditField       matlab.ui.control.NumericEditField
        TrajectoryLengthEditFieldLabel  matlab.ui.control.Label
        UseMotionProfileEditField       matlab.ui.control.NumericEditField
        UseMotionProfileEditFieldLabel  matlab.ui.control.Label
        MotionProfileDropDown           matlab.ui.control.DropDown
        MotionProfileDropDownLabel      matlab.ui.control.Label
        LoadMotionConfigButton          matlab.ui.control.Button
        SaveMotionConfigButton          matlab.ui.control.Button
    end


    properties (Access = public)
        ga_halt = false;
        fmin_halt = false;
    end

    properties (Access = private)
        CONV_PARAMS_FIELDS = {'bed-belt friction','shaft damping','shaft radius','pulley radius','load mass','shaft mass','belt mass','pulley mass','inclination angle','gear efficiency','gear ratio'};
        CONV_PARAMS_VARS = {'mu_s',               'b',            'r_s',         'r_p',           'm_l',     'm_s',       'm_b',      'm_p',        'angl',             'n_g',             'N'}

        MOTOR_PARAMS_FIELDS = {'use operating limits','max continuous current','max continuous torque','rotor inertia','rotor damping','motor constant, ke','coil resistance','coil inductance','input voltage'};
        MOTOR_PARAMS_VARS = {'USE_MAX_OP',         'max_i',                  'max_trq',             'J_m',          'b_m',           'Ke',                'R',              'L',              'V'};

        MOTION_PARAMS_FIELDS = {'use motion profile','trajectory length','total time','slew rate, k','max velocity','max acceleration','max deceleration','acceleration time','deceleration time','starting time','motion profile','optimise params','use params'};
        MOTION_PARAMS_VARS = {'MP_ON',                'len',              'tm',        'k',          'max_vel',     'max_acc',         'max_dec',          't1',               't3',              'ta',           'MP',            'opt_params',      'no_opt_params'};

        MAP_CONV_FIELD_VAR= {};
        MAP_CONV_VAR_FIELD= {};

        MAP_MOTOR_VAR_FIELD= {};
        MAP_MOTOR_FIELD_VAR= {};

        MAP_MOTION_VAR_FIELD= {};
        MAP_MOTION_FIELD_VAR= {};
    end


    methods (Access = private)
        function mapParameters(app)
            app.MAP_CONV_FIELD_VAR = containers.Map(app.CONV_PARAMS_FIELDS, app.CONV_PARAMS_VARS);
            app.MAP_CONV_VAR_FIELD = containers.Map(app.CONV_PARAMS_VARS, app.CONV_PARAMS_FIELDS);

            app.MAP_MOTOR_FIELD_VAR = containers.Map(app.MOTOR_PARAMS_FIELDS, app.MOTOR_PARAMS_VARS);
            app.MAP_MOTOR_VAR_FIELD = containers.Map(app.MOTOR_PARAMS_VARS, app.MOTOR_PARAMS_FIELDS);

            app.MAP_MOTION_FIELD_VAR = containers.Map(app.MOTION_PARAMS_FIELDS, app.MOTION_PARAMS_VARS);
            app.MAP_MOTION_VAR_FIELD = containers.Map(app.MOTION_PARAMS_VARS, app.MOTION_PARAMS_FIELDS);
        end

        function saveParamsToWorkspace(app)
            conv_params = getConveyorParams(app);
            motor_params = getMotorParams(app);
            motion_params = getMotionParams(app);

            param_maps = {conv_params, motor_params, motion_params};

            for i=1:length(param_maps)
                mparams = param_maps{i};
                kys = keys(mparams);
                vls = values(mparams);
                for j=1:length(kys)
                    ky = kys{j};
                    vl = vls{j};
                    assignin("base",ky,vl);
                end
            end
            assignin("base","param_maps",{conv_params,motor_params,motion_params});
        end

        function res = getConveyorParams(app)
            mapParameters(app);
            cvp = app.ConveyorParametersPanel.Children;
            cvp_names = cell(1, length(cvp));
            cvp_values = cell(1, length(cvp));

            for i = 1:length(cvp)
                try
                    p_elem_val = cvp(i-1).Value;
                    elem_txt = cvp(i).Text;
                    x = app.MAP_CONV_FIELD_VAR(lower(elem_txt));
                    cvp_names{i} = x;
                    cvp_values{i} = p_elem_val;
                catch
                end
            end

            cvp_names = cvp_names(~cellfun('isempty',cvp_names));
            cvp_values = cvp_values(~cellfun('isempty',cvp_values));
            cvpMap = containers.Map(cvp_names, cvp_values);
            res = cvpMap;
        end

        function res = getMotorParams(app)
            mapParameters(app);
            mtrp = app.MotorParametersPanel.Children;
            mtrp_names = cell(1, length(mtrp));
            mtrp_values = cell(1, length(mtrp));

            for i = 1:length(mtrp)
                try
                    p_elem_val = mtrp(i-1).Value;
                    elem_txt = mtrp(i).Text;
                    x = app.MAP_MOTOR_FIELD_VAR(lower(elem_txt));
                    mtrp_names{i} = x;
                    mtrp_values{i} = p_elem_val;
                catch
                end
            end

            mtrp_names = mtrp_names(~cellfun('isempty',mtrp_names));
            mtrp_values = mtrp_values(~cellfun('isempty',mtrp_values));
            mtrpMap = containers.Map(mtrp_names, mtrp_values);
            res = mtrpMap;
        end

        function res = getMotionParams(app)
            mapParameters(app)
            mpp = app.MotionParametersPanel.Children;
            mpp_names = cell(1, length(mpp));
            mpp_values = cell(1, length(mpp));

            for i = 1:length(mpp)
                try
                    p_elem_val = mpp(i-1).Value;
                    elem_txt = mpp(i).Text;
                    x = app.MAP_MOTION_FIELD_VAR(lower(elem_txt));
                    mpp_names{i} = x;
                    mpp_values{i} = p_elem_val;
                catch
                end
            end

            mpp_names = mpp_names(~cellfun('isempty',mpp_names));
            mpp_values = mpp_values(~cellfun('isempty',mpp_values));
            mppMap = containers.Map(mpp_names, mpp_values);
            res = mppMap;
        end

        function runSetupCalc(~)
            evalin("base","pth = [fileparts(mfilename('fullpath')) '/appUtils/setup_calc'];run(pth)");
            %             run("appUtils/setup_calc.m");
        end

        function runControllerCalc(~)
            evalin("base","pth = [fileparts(mfilename('fullpath')) '/appUtils/controller_calc'];run(pth)");
            %             run("appUtils/controller_calc.m");
        end

        function runOptimiserCalc(~)
            evalin("base","pth = [fileparts(mfilename('fullpath')) '/appUtils/optimiser_calc'];run(pth)");
%             evalin("base","pth = [fileparts(mfilename('fullpath')) '/appUtils/optimiser_calc_2'];run(pth)");
            %             run("appUtils/optimiser_calc.m");
        end

        function saveConfig(app,type)
            [fname,pname] = uiputfile('*.mat');
            fullname = fullfile(pname,fname);
            if(type==1)
                conv_config = getConveyorParams(app);
                save(fullname,"conv_config");
            elseif(type==2)
                motor_config = getMotorParams(app);
                save(fullname,"motor_config");
            elseif(type==3)
                motion_config = getMotionParams(app);
                save(fullname,"motion_config");
            end

        end

        function loadConfig(app,type)
            mapParameters(app);
            [fname,pname] = uigetfile('*.mat');
            fullname = fullfile(pname,fname);
            if(type==1)
                load(fullname,'conv_config');
                pconfig = conv_config;
                pnvp = app.ConveyorParametersPanel.Children;
                pmap = app.MAP_CONV_FIELD_VAR;
            elseif(type==2)
                load(fullname,'motor_config');
                pconfig = motor_config;
                pnvp = app.MotorParametersPanel.Children;
                pmap = app.MAP_MOTOR_FIELD_VAR;
            elseif(type==3)
                load(fullname,'motion_config');
                pconfig = motion_config;
                pnvp = app.MotionParametersPanel.Children;
                pmap = app.MAP_MOTION_FIELD_VAR;
            end
            for i = 1:length(pnvp)
                try
                    elem_txt = pnvp(i).Text;
                    var_name = pmap(lower(elem_txt));
                    var_val = pconfig(var_name);
                    pnvp(i-1).Value = var_val;
                catch
                end
            end
        end


        function drawAllGraphs(app, simData)
            out = simData;
            % ----------------------------------------------------------------------------------------
            ax = app.ref_mp_UIAxes;
            cla(ax);
            mptxt = sprintf('Reference %s Motion Profile',app.MotionProfileDropDown.Value);
            hold(ax,"on");
            title(ax, mptxt);
            plot(ax, out.tout, out.mp_data.ang_acc.Data,'b-','LineWidth',1.5, 'Color', "#0072BD");
            plot(ax, out.tout, out.mp_data.ang_vel.Data,'LineWidth',1.5, 'Color', "#EDB120");
            plot(ax, out.tout, out.mp_data.ang_pos.Data,'LineWidth',1.5, 'Color', "#D95319");
            legend(ax,{'Acceleration','Velocity','Position'},'Location','northwest');
            xlabel(ax,'Time/s');
            ylabel(ax,'rad/s^{2}, rad/s, rad');
            hold(ax,"off");
            grid(ax,"on");

            ax = app.actual_mp_UIAxes;
            cla(ax);
            mptxt = sprintf('Actual %s Motion Profile',app.MotionProfileDropDown.Value);
            hold(ax,"on");
            title(ax, mptxt);
            plot(ax, out.tout, out.load_data.signal_0__.Data,'b-','LineWidth',1.5, 'Color', "#0072BD");
            plot(ax, out.tout, out.load_data.signal_0_.Data,'LineWidth',1.5, 'Color', "#EDB120");
            plot(ax, out.tout, out.load_data.signal_0.Data,'LineWidth',1.5, 'Color', "#D95319");
            legend(ax,{'Acceleration','Velocity','Position'},'Location','northwest');
            xlabel(ax,'Time/s');
            ylabel(ax,'rad/s^{2}, rad/s, rad');
            hold(ax,"off");
            grid(ax,"on");


            % ----------------------------------------------------------------------------------------
            ax = app.ref_vel_UIAxes;
            cla(ax);
            plot(ax,out.tout, out.mp_data.ang_vel.Data,'LineWidth',1.5, 'Color', "#EDB120");
            title(ax,'Expected Load Velocity');
            xlabel(ax,'Time/s');
            ylabel(ax,'Velocity/rads^{1}');
            grid(ax,"on");

            ax = app.actual_vel_UIAxes;
            cla(ax);
            plot(ax,out.tout, out.load_data.signal_0_.Data,'LineWidth',1.5, 'Color', "#EDB120");
            title(ax,'Actual Load Velocity');
            xlabel(ax,'Time/s');
            ylabel(ax,'Velocity/rads^{-1}');
            grid(ax,"on");

            ax = app.ref_pos_UIAxes;
            cla(ax);
            plot(ax,out.tout, out.mp_data.ang_pos.Data,'LineWidth',1.5, 'Color', "#D95319");
            title(ax,'Expected Load Position');
            xlabel(ax,'Time/s');
            ylabel(ax,'Position/rad');
            grid(ax,"on");

            ax = app.actual_pos_UIAxes;
            cla(ax);
            plot(ax,out.tout, out.load_data.signal_0.Data,'LineWidth',1.5, 'Color', "#D95319");
            title(ax,'Actual Load Position');
            xlabel(ax,'Time/s');
            ylabel(ax,'Position/rad');
            grid(ax,"on");

            ax = app.ref_acc_UIAxes;
            cla(ax);
            plot(ax,out.tout, out.mp_data.ang_acc.Data,'LineWidth',1.5, 'Color', "#D95319");
            title(ax,'Expected Load Acceleration');
            xlabel(ax,'Time/s');
            ylabel(ax,'Acceleration/rads^{-2}');
            grid(ax,"on");

            ax = app.actual_acc_UIAxes;
            cla(ax);
            plot(ax,out.tout, out.load_data.signal_0__.Data,'LineWidth',1.5, 'Color', "#D95319");
            title(ax,'Actual Load Acceleration');
            xlabel(ax,'Time/s');
            ylabel(ax,'Acceleration/rads^{-2}');
            grid(ax,"on");

            % ------------------------------------------------------------------------------------------

            ax = app.actual_energy_UIAxes;
            cla(ax);
            plot(ax,out.tout, out.motor_data.E.Data,'LineWidth',1.5, 'Color', "#FF0000");
            title(ax,'Energy Used');
            xlabel(ax,'Time/s');
            ylabel(ax,'Energy/J');
            grid(ax,"on");
            omedta = out.motor_data.E.Data;
            app.ActualEnergyUsageEditField.Value = omedta(end);

            % ------------------------------------------------------------------------------------------

            ax = app.curr_UIAxes;
            cla(ax);
            plot(ax,out.tout, out.motor_data.i.Data,'LineWidth',1.5, 'Color', "#0000FF");
            title(ax,'Motor Current');
            xlabel(ax,'Time/s');
            ylabel(ax,'Current/A');
            grid(ax,"on");

            ax = app.trq_UIAxes;
            cla(ax);
            plot(ax,out.tout, out.motor_data.Tm.Data,'LineWidth',1.5, 'Color', "#FF0000");
            title(ax,'Motor Torque');
            xlabel(ax,'Time/s');
            ylabel(ax,'Torque/Nm');
            grid(ax,"on");

            ax = app.volt_UIAxes;
            cla(ax);
            plot(ax,out.tout, out.motor_data.V.Data,'LineWidth',1.5, 'Color', "#FF0000");
            title(ax,'Motor Voltage');
            xlabel(ax,'Time/s');
            ylabel(ax,'Voltage/V');
            grid(ax,"on");

            
            % ------------------------------------------------------------------------------------------
            ax = app.pos_error_UIAxes;
            cla(ax);
            plot(ax,out.tout, out.mp_data.ang_pos.Data-out.load_data.signal_0.Data,'LineWidth',1.5, 'Color', "#D95319");
            title(ax,'Position Error');
            xlabel(ax,'Time/s');
            ylabel(ax,'Position/rad');
            grid(ax,"on");

            ax = app.vel_error_UIAxes;
            cla(ax);
            plot(ax,out.tout, out.mp_data.ang_vel.Data-out.load_data.signal_0_.Data,'LineWidth',1.5, 'Color', "#EDB120");
            title(ax,'Velocity Error');
            xlabel(ax,'Time/s');
            ylabel(ax,'Velocity/rads^{-1}');
            grid(ax,"on");

            ax = app.acc_error_UIAxes;
            cla(ax);
            plot(ax,out.tout, out.mp_data.ang_acc.Data-out.load_data.signal_0__.Data,'LineWidth',1.5, 'Color', "#0072BD");
            title(ax,'Acceleration Error');
            xlabel(ax,'Time/s');
            ylabel(ax,'Acceleration/rads^{-2}');
            grid(ax,"on");
        end
    end

    % ALSO if simulink model does not cause an issue, change code to work
    % in this workspace instead of the base workspace


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunCalculatorOptimiserButton
        function RunCalculatorOptimiserButtonPushed(app, event)
            %             app.RunCalculatorOptimiserButton.Enable = false;
            %             app.RunSimulationButton.Enable = false;
            app.AvailableLampLabel.Text = 'Running Calculator/Optimiser';
            app.AvailableLamp.Color = [1.00,0.00,0.00];
            cla(app.ga_UIAxes);cla(app.fmin_UIAxes);
            app.GAlogTextArea.Value = cat(1,app.GAlogTextArea.Value,'-----------------------');
            app.ga_halt = false;
            app.fmin_halt = false;
            saveParamsToWorkspace(app);
            runSetupCalc(app);
            runOptimiserCalc(app);
            ga_out = evalin('base','calc_opt_message');
            app.GAlogTextArea.Value=cat(1,app.GAlogTextArea.Value,ga_out);
            app.AvailableLampLabel.Text = 'Available';
            app.AvailableLamp.Color = [0.00,1.00,0.00];
            %             app.RunCalculatorOptimiserButton.Enable = true;
            %             app.RunSimulationButton.Enable = true;
        end

        % Button pushed function: LoadConveyorConfigButton
        function LoadConveyorConfigButtonPushed(app, event)
            loadConfig(app,1);
        end

        % Button pushed function: SaveConveyorConfigButton
        function SaveConveyorConfigButtonPushed(app, event)
            saveConfig(app,1);
        end

        % Button pushed function: LoadMotorConfigButton
        function LoadMotorConfigButtonPushed(app, event)
            loadConfig(app,2);
        end

        % Button pushed function: SaveMotorConfigButton
        function SaveMotorConfigButtonPushed(app, event)
            saveConfig(app,2);
        end

        % Button pushed function: LoadMotionConfigButton
        function LoadMotionConfigButtonPushed(app, event)
            loadConfig(app,3);
        end

        % Button pushed function: SaveMotionConfigButton
        function SaveMotionConfigButtonPushed(app, event)
            saveConfig(app,3);
        end

        % Value changed function: OptimiseParamsDropDown
        function OptimiseParamsDropDownValueChanged(app, event)
            value = app.OptimiseParamsDropDown.Value;
            if(strcmpi(value,'none')==1)
                app.UseParamsDropDown.Enable = 1;
            else
                app.UseParamsDropDown.Enable = 0;
            end
        end

        % Button pushed function: ga_HaltButton
        function ga_HaltButtonPushed(app, event)
            app.ga_halt = true;
%               error("GA Terminated");
        end

        % Button pushed function: fmin_HaltButton
        function fmin_HaltButtonPushed(app, event)
            app.fmin_halt = true;
%             error("FMINCON Terminated");
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.GAlogTextArea.Value = '';
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            %                         app.RunCalculatorOptimiserButton.Enable = false;
            %             app.RunSimulationButton.Enable = false;
            app.AvailableLampLabel.Text = 'Running Simulation';
            app.AvailableLamp.Color = [1.00,0.00,0.00];
            evalin("base","pth = [fileparts(mfilename('fullpath')) '/test_big_model'];wsSimData = sim(pth);");
            %             sss = sim("test_big_model.slx");
            simData = evalin('base','wsSimData');
            drawAllGraphs(app,simData);
            app.AvailableLampLabel.Text = 'Available';
            app.AvailableLamp.Color = [0.00,1.00,0.00];
            %                         app.RunCalculatorOptimiserButton.Enable = true;
            %             app.RunSimulationButton.Enable = true;
        end

        % Button pushed function: StopSimulationButton
        function StopSimulationButtonPushed(app, event)
            %             evalin("base","pth = [fileparts(mfilename('fullpath')) '/test_big_model'];set_param('test_big_model', 'SimulationCommand', 'stop');");
            set_param('test_big_model', 'SimulationCommand', 'stop');
            app.AvailableLampLabel.Text = 'Available';
            app.AvailableLamp.Color = [0.00,1.00,0.00];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Tag = 'MotorOptimisationAppTag';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConveyorTab = uitab(app.TabGroup);
            app.ConveyorTab.Title = 'Conveyor';
            app.SaveConveyorConfigButton = uibutton(app.ConveyorTab, 'push');
            app.SaveConveyorConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConveyorConfigButtonPushed, true);
            app.SaveConveyorConfigButton.Text = ' Save Conveyor Config';
            app.LoadConveyorConfigButton = uibutton(app.ConveyorTab, 'push');
            app.LoadConveyorConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConveyorConfigButtonPushed, true);
            app.LoadConveyorConfigButton.Text = 'Load Conveyor Config';
            app.ConveyorParametersPanel = uipanel(app.ConveyorTab);
            app.ConveyorParametersPanel.Title = 'Conveyor Parameters';
            app.GearRatioEditFieldLabel = uilabel(app.ConveyorParametersPanel);
            app.GearRatioEditFieldLabel.Text = 'Gear Ratio';
            app.GearRatioEditField = uieditfield(app.ConveyorParametersPanel, 'numeric');
            app.GearRatioEditField.Limits = [1 Inf];
            app.GearRatioEditField.Value = 1;
            app.GearEfficiencyEditFieldLabel = uilabel(app.ConveyorParametersPanel);
            app.GearEfficiencyEditFieldLabel.Text = 'Gear Efficiency';
            app.GearEfficiencyEditField = uieditfield(app.ConveyorParametersPanel, 'numeric');
            app.GearEfficiencyEditField.Limits = [0.01 1];
            app.GearEfficiencyEditField.Value = 1;
            app.InclinationAngleEditFieldLabel = uilabel(app.ConveyorParametersPanel);
            app.InclinationAngleEditFieldLabel.Text = 'Inclination Angle';
            app.InclinationAngleEditField = uieditfield(app.ConveyorParametersPanel, 'numeric');
            app.InclinationAngleEditField.Limits = [0 89];
            app.PulleymassEditFieldLabel = uilabel(app.ConveyorParametersPanel);
            app.PulleymassEditFieldLabel.Text = 'Pulley mass';
            app.PulleymassEditField = uieditfield(app.ConveyorParametersPanel, 'numeric');
            app.PulleymassEditField.Limits = [0 Inf];
            app.BeltmassEditFieldLabel = uilabel(app.ConveyorParametersPanel);
            app.BeltmassEditFieldLabel.Text = 'Belt mass';
            app.BeltmassEditField = uieditfield(app.ConveyorParametersPanel, 'numeric');
            app.BeltmassEditField.Limits = [0 Inf];
            app.ShaftmassEditFieldLabel = uilabel(app.ConveyorParametersPanel);
            app.ShaftmassEditFieldLabel.Text = 'Shaft mass';
            app.ShaftmassEditField = uieditfield(app.ConveyorParametersPanel, 'numeric');
            app.ShaftmassEditField.Limits = [0 Inf];
            app.LoadmassEditFieldLabel = uilabel(app.ConveyorParametersPanel);
            app.LoadmassEditFieldLabel.Text = 'Load mass';
            app.LoadmassEditField = uieditfield(app.ConveyorParametersPanel, 'numeric');
            app.LoadmassEditField.Limits = [0 Inf];
            app.PulleyradiusEditFieldLabel = uilabel(app.ConveyorParametersPanel);
            app.PulleyradiusEditFieldLabel.Text = 'Pulley radius';
            app.PulleyradiusEditField = uieditfield(app.ConveyorParametersPanel, 'numeric');
            app.PulleyradiusEditField.Limits = [0 Inf];
            app.ShaftradiusEditFieldLabel = uilabel(app.ConveyorParametersPanel);
            app.ShaftradiusEditFieldLabel.Text = 'Shaft radius';
            app.ShaftradiusEditField = uieditfield(app.ConveyorParametersPanel, 'numeric');
            app.ShaftradiusEditField.Limits = [0 Inf];
            app.ShaftdampingEditFieldLabel = uilabel(app.ConveyorParametersPanel);
            app.ShaftdampingEditFieldLabel.Text = 'Shaft damping';
            app.ShaftdampingEditField = uieditfield(app.ConveyorParametersPanel, 'numeric');
            app.ShaftdampingEditField.Limits = [0 Inf];
            app.BedBeltfrictionEditFieldLabel = uilabel(app.ConveyorParametersPanel);
            app.BedBeltfrictionEditFieldLabel.Text = 'Bed-Belt friction';
            app.BedBeltfrictionEditField = uieditfield(app.ConveyorParametersPanel, 'numeric');
            app.BedBeltfrictionEditField.Limits = [0 1];
            app.degLabel = uilabel(app.ConveyorParametersPanel);
            app.degLabel.Text = 'deg';
            app.kgLabel = uilabel(app.ConveyorParametersPanel);
            app.kgLabel.Text = 'kg';
            app.kgLabel_2 = uilabel(app.ConveyorParametersPanel);
            app.kgLabel_2.Text = 'kg';
            app.kgLabel_3 = uilabel(app.ConveyorParametersPanel);
            app.kgLabel_3.Text = 'kg';
            app.kgLabel_4 = uilabel(app.ConveyorParametersPanel);
            app.kgLabel_4.Text = 'kg';
            app.mLabel = uilabel(app.ConveyorParametersPanel);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.ConveyorParametersPanel);
            app.mLabel_2.Text = 'm';
            app.NmradsLabel = uilabel(app.ConveyorParametersPanel);
            app.NmradsLabel.Text = 'Nm/(rad/s)';
            app.Label_9 = uilabel(app.ConveyorParametersPanel);
            app.Label_9.Text = '0-1';
            app.Label_23 = uilabel(app.ConveyorParametersPanel);
            app.Label_23.Text = '0-1';
            app.MotorTab = uitab(app.TabGroup);
            app.MotorTab.Title = 'Motor';
            app.SaveMotorConfigButton = uibutton(app.MotorTab, 'push');
            app.SaveMotorConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMotorConfigButtonPushed, true);
            app.SaveMotorConfigButton.Text = ' Save Motor Config';
            app.LoadMotorConfigButton = uibutton(app.MotorTab, 'push');
            app.LoadMotorConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMotorConfigButtonPushed, true);
            app.LoadMotorConfigButton.Text = 'Load Motor Config';
            app.MotorParametersPanel = uipanel(app.MotorTab);
            app.MotorParametersPanel.Title = 'Motor Parameters';
            app.UseOperatingLimitsEditFieldLabel = uilabel(app.MotorParametersPanel);
            app.UseOperatingLimitsEditFieldLabel.Text = 'Use Operating Limits';
            app.UseOperatingLimitsEditField = uieditfield(app.MotorParametersPanel, 'numeric');
            app.MaxContinuousCurrentEditFieldLabel = uilabel(app.MotorParametersPanel);
            app.MaxContinuousCurrentEditFieldLabel.Text = 'Max Continuous Current';
            app.MaxContinuousCurrentEditField = uieditfield(app.MotorParametersPanel, 'numeric');
            app.MaxContinuousCurrentEditField.Limits = [0 Inf];
            app.MaxContinuousTorqueEditFieldLabel = uilabel(app.MotorParametersPanel);
            app.MaxContinuousTorqueEditFieldLabel.Text = 'Max Continuous Torque';
            app.MaxContinuousTorqueEditField = uieditfield(app.MotorParametersPanel, 'numeric');
            app.MaxContinuousTorqueEditField.Limits = [0 Inf];
            app.RotorinertiaEditFieldLabel = uilabel(app.MotorParametersPanel);
            app.RotorinertiaEditFieldLabel.Text = 'Rotor inertia';
            app.RotorinertiaEditField = uieditfield(app.MotorParametersPanel, 'numeric');
            app.RotorinertiaEditField.Limits = [0 Inf];
            app.RotordampingEditFieldLabel = uilabel(app.MotorParametersPanel);
            app.RotordampingEditFieldLabel.Text = 'Rotor damping';
            app.RotordampingEditField = uieditfield(app.MotorParametersPanel, 'numeric');
            app.RotordampingEditField.Limits = [0 Inf];
            app.MotorConstantKeEditFieldLabel = uilabel(app.MotorParametersPanel);
            app.MotorConstantKeEditFieldLabel.Text = 'Motor Constant, Ke';
            app.MotorConstantKeEditField = uieditfield(app.MotorParametersPanel, 'numeric');
            app.MotorConstantKeEditField.Limits = [0 Inf];
            app.CoilResistanceEditFieldLabel = uilabel(app.MotorParametersPanel);
            app.CoilResistanceEditFieldLabel.Text = 'Coil Resistance';
            app.CoilResistanceEditField = uieditfield(app.MotorParametersPanel, 'numeric');
            app.CoilResistanceEditField.Limits = [0 Inf];
            app.CoilInductanceEditFieldLabel = uilabel(app.MotorParametersPanel);
            app.CoilInductanceEditFieldLabel.Text = 'Coil Inductance';
            app.CoilInductanceEditField = uieditfield(app.MotorParametersPanel, 'numeric');
            app.CoilInductanceEditField.Limits = [0 Inf];
            app.InputVoltageEditFieldLabel = uilabel(app.MotorParametersPanel);
            app.InputVoltageEditFieldLabel.Text = 'Input Voltage';
            app.InputVoltageEditField = uieditfield(app.MotorParametersPanel, 'numeric');
            app.InputVoltageEditField.Limits = [0 Inf];
            app.NmsradLabel = uilabel(app.MotorParametersPanel);
            app.NmsradLabel.Text = 'Nms/rad';
            app.NmALabel = uilabel(app.MotorParametersPanel);
            app.NmALabel.Text = 'Nm/A';
            app.OhmsLabel = uilabel(app.MotorParametersPanel);
            app.OhmsLabel.Text = 'Ohms';
            app.HLabel = uilabel(app.MotorParametersPanel);
            app.HLabel.Text = 'H';
            app.VLabel = uilabel(app.MotorParametersPanel);
            app.VLabel.Text = 'V';
            app.ALabel = uilabel(app.MotorParametersPanel);
            app.ALabel.Text = 'A';
            app.NmLabel = uilabel(app.MotorParametersPanel);
            app.NmLabel.Text = 'Nm';
            app.Kgmsup2supLabel = uilabel(app.MotorParametersPanel);
            app.Kgmsup2supLabel.Interpreter = 'html';
            app.Kgmsup2supLabel.Text = 'Kgm<sup>2</sup>';
            app.Label_22 = uilabel(app.MotorParametersPanel);
            app.Label_22.Text = '[0 | 1]';
            app.MotionTab = uitab(app.TabGroup);
            app.MotionTab.Title = 'Motion';
            app.SaveMotionConfigButton = uibutton(app.MotionTab, 'push');
            app.SaveMotionConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMotionConfigButtonPushed, true);
            app.SaveMotionConfigButton.Text = ' Save Motion Config';
            app.LoadMotionConfigButton = uibutton(app.MotionTab, 'push');
            app.LoadMotionConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMotionConfigButtonPushed, true);
            app.LoadMotionConfigButton.Text = 'Load Motion Config';
            app.MotionParametersPanel = uipanel(app.MotionTab);
            app.MotionParametersPanel.Title = 'Motion Parameters';
            app.MotionProfileDropDownLabel = uilabel(app.MotionParametersPanel);
            app.MotionProfileDropDownLabel.Text = 'Motion Profile';
            app.MotionProfileDropDown = uidropdown(app.MotionParametersPanel);
            app.MotionProfileDropDown.Items = {'Trapezoidal', 'Cosine', 'Cubic'};
            app.MotionProfileDropDown.Value = 'Trapezoidal';
            app.UseMotionProfileEditFieldLabel = uilabel(app.MotionParametersPanel);
            app.UseMotionProfileEditFieldLabel.Text = 'Use Motion Profile';
            app.UseMotionProfileEditField = uieditfield(app.MotionParametersPanel, 'numeric');
            app.UseMotionProfileEditField.Limits = [0 1];
            app.UseMotionProfileEditField.RoundFractionalValues = 'on';
            app.UseMotionProfileEditField.Value = 1;
            app.TrajectoryLengthEditFieldLabel = uilabel(app.MotionParametersPanel);
            app.TrajectoryLengthEditFieldLabel.Text = 'Trajectory Length';
            app.TrajectoryLengthEditField = uieditfield(app.MotionParametersPanel, 'numeric');
            app.TrajectoryLengthEditField.Limits = [0 Inf];
            app.TotalTimeEditFieldLabel = uilabel(app.MotionParametersPanel);
            app.TotalTimeEditFieldLabel.Text = 'Total Time';
            app.TotalTimeEditField = uieditfield(app.MotionParametersPanel, 'numeric');
            app.TotalTimeEditField.Limits = [0 Inf];
            app.SlewRatekEditFieldLabel = uilabel(app.MotionParametersPanel);
            app.SlewRatekEditFieldLabel.Text = 'Slew Rate, k';
            app.SlewRatekEditField = uieditfield(app.MotionParametersPanel, 'numeric');
            app.SlewRatekEditField.Limits = [0 0.99];
            app.MaxVelocityEditFieldLabel = uilabel(app.MotionParametersPanel);
            app.MaxVelocityEditFieldLabel.Text = 'Max Velocity';
            app.MaxVelocityEditField = uieditfield(app.MotionParametersPanel, 'numeric');
            app.MaxVelocityEditField.Limits = [0 Inf];
            app.MaxAccelerationEditFieldLabel = uilabel(app.MotionParametersPanel);
            app.MaxAccelerationEditFieldLabel.Text = 'Max Acceleration';
            app.MaxAccelerationEditField = uieditfield(app.MotionParametersPanel, 'numeric');
            app.MaxAccelerationEditField.Limits = [0 Inf];
            app.MaxDecelerationEditFieldLabel = uilabel(app.MotionParametersPanel);
            app.MaxDecelerationEditFieldLabel.Text = 'Max Deceleration';
            app.MaxDecelerationEditField = uieditfield(app.MotionParametersPanel, 'numeric');
            app.MaxDecelerationEditField.Limits = [0 Inf];
            app.StartingTimeEditFieldLabel = uilabel(app.MotionParametersPanel);
            app.StartingTimeEditFieldLabel.Text = 'Starting Time';
            app.StartingTimeEditField = uieditfield(app.MotionParametersPanel, 'numeric');
            app.StartingTimeEditField.Limits = [0 Inf];
            app.StartingTimeEditField.Editable = 'off';
            app.USEPARAMSLabel = uilabel(app.MotionParametersPanel);
            app.USEPARAMSLabel.Text = 'Optimise Params';
            app.OptimiseParamsDropDown = uidropdown(app.MotionParametersPanel);
            app.OptimiseParamsDropDown.Items = {'NONE', 'tm,t1,t3,Vm - given acc,dec,k', 'k,t1,t3,Vm - given acc,dec,tm', 'ALL - given acc,dec', 'tm,t1,t3,acc,dec - given Vm,k', 'k,t1,t3,acc,dec - given Vm,tm', 'ALL - given Vm', 'tm,k,t1,t3 - given Vm,acc,dec', 'ALL - given k', 'ALL - given tm', 't1,t3,Vm,acc,dec - given k,tm'};
            app.OptimiseParamsDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimiseParamsDropDownValueChanged, true);
            app.OptimiseParamsDropDown.Value = 'NONE';
            app.AccelerationTimeEditFieldLabel = uilabel(app.MotionParametersPanel);
            app.AccelerationTimeEditFieldLabel.Text = 'Acceleration Time';
            app.AccelerationTimeEditField = uieditfield(app.MotionParametersPanel, 'numeric');
            app.AccelerationTimeEditField.Limits = [0 Inf];
            app.DecelerationTimeEditFieldLabel = uilabel(app.MotionParametersPanel);
            app.DecelerationTimeEditFieldLabel.Text = 'Deceleration Time';
            app.DecelerationTimeEditField = uieditfield(app.MotionParametersPanel, 'numeric');
            app.DecelerationTimeEditField.Limits = [0 Inf];
            app.UseParamsDropDownLabel = uilabel(app.MotionParametersPanel);
            app.UseParamsDropDownLabel.Text = 'Use Params';
            app.UseParamsDropDown = uidropdown(app.MotionParametersPanel);
            app.UseParamsDropDown.Items = {'Acceleration and Deceleration Times', 'Max Acceleration and Deceleration'};
            app.UseParamsDropDown.Value = 'Acceleration and Deceleration Times';
            app.radLabel = uilabel(app.MotionParametersPanel);
            app.radLabel.Text = 'rad';
            app.sLabel = uilabel(app.MotionParametersPanel);
            app.sLabel.Text = 's';
            app.radsLabel = uilabel(app.MotionParametersPanel);
            app.radsLabel.Text = 'rad/s';
            app.radssup2supLabel = uilabel(app.MotionParametersPanel);
            app.radssup2supLabel.Interpreter = 'html';
            app.radssup2supLabel.Text = 'rad/s<sup>2</sup>';
            app.radssup2supLabel_2 = uilabel(app.MotionParametersPanel);
            app.radssup2supLabel_2.Interpreter = 'html';
            app.radssup2supLabel_2.Text = 'rad/s<sup>2</sup>';
            app.sLabel_2 = uilabel(app.MotionParametersPanel);
            app.sLabel_2.Text = 's';
            app.sLabel_3 = uilabel(app.MotionParametersPanel);
            app.sLabel_3.Text = 's';
            app.sLabel_4 = uilabel(app.MotionParametersPanel);
            app.sLabel_4.Text = 's';
            app.Label_21 = uilabel(app.MotionParametersPanel);
            app.Label_21.Text = '[0 | 1]';
            app.StopSimulationButton = uibutton(app.UIFigure, 'push');
            app.StopSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StopSimulationButtonPushed, true);
            app.StopSimulationButton.Text = 'Stop Simulation';
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.ResultsViewerPanel = uipanel(app.UIFigure);
            app.ResultsViewerPanel.Title = 'Results Viewer';
            app.TabGroup2 = uitabgroup(app.ResultsViewerPanel);
            app.CalculationsOptimisationTab = uitab(app.TabGroup2);
            app.CalculationsOptimisationTab.Title = 'Calculations/Optimisation';
            app.TabGroup3 = uitabgroup(app.CalculationsOptimisationTab);
            app.GraphsTab = uitab(app.TabGroup3);
            app.GraphsTab.Title = 'Graphs';
            app.ga_UIAxes = uiaxes(app.GraphsTab);
            title(app.ga_UIAxes, 'ga')
            xlabel(app.ga_UIAxes, 'X')
            ylabel(app.ga_UIAxes, 'Y')
            zlabel(app.ga_UIAxes, 'Z')
            app.fmin_UIAxes = uiaxes(app.GraphsTab);
            title(app.fmin_UIAxes, 'fmincon')
            xlabel(app.fmin_UIAxes, 'X')
            ylabel(app.fmin_UIAxes, 'Y')
            zlabel(app.fmin_UIAxes, 'Z')
            app.ga_HaltButton = uibutton(app.GraphsTab, 'push');
            app.ga_HaltButton.ButtonPushedFcn = createCallbackFcn(app, @ga_HaltButtonPushed, true);
            app.ga_HaltButton.Text = 'Halt';
            app.fmin_HaltButton = uibutton(app.GraphsTab, 'push');
            app.fmin_HaltButton.ButtonPushedFcn = createCallbackFcn(app, @fmin_HaltButtonPushed, true);
            app.fmin_HaltButton.Text = 'Halt';
            app.GeneticAlgoLabel = uilabel(app.GraphsTab);
            app.GeneticAlgoLabel.Text = 'Genetic Algo';
            app.fminconLabel = uilabel(app.GraphsTab);
            app.fminconLabel.Text = 'fmincon';
            app.LogTab = uitab(app.TabGroup3);
            app.LogTab.Title = 'Log';
            app.GAlogTextArea = uitextarea(app.LogTab);
            app.GAlogTextArea.Editable = 'off';
            app.ClearButton = uibutton(app.LogTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.LoadMotionProfileTab = uitab(app.TabGroup2);
            app.LoadMotionProfileTab.Title = 'Load Motion Profile';
            app.TabGroup4 = uitabgroup(app.LoadMotionProfileTab);
            app.AllTab = uitab(app.TabGroup4);
            app.AllTab.Title = 'All';
            app.ref_mp_UIAxes = uiaxes(app.AllTab);
            title(app.ref_mp_UIAxes, 'Reference Motion Profile')
            xlabel(app.ref_mp_UIAxes, 'X')
            ylabel(app.ref_mp_UIAxes, 'Y')
            zlabel(app.ref_mp_UIAxes, 'Z')
            app.actual_mp_UIAxes = uiaxes(app.AllTab);
            title(app.actual_mp_UIAxes, 'Actual Motion Profile')
            xlabel(app.actual_mp_UIAxes, 'X')
            ylabel(app.actual_mp_UIAxes, 'Y')
            zlabel(app.actual_mp_UIAxes, 'Z')
            app.PositonTab = uitab(app.TabGroup4);
            app.PositonTab.Title = 'Positon';
            app.ref_pos_UIAxes = uiaxes(app.PositonTab);
            title(app.ref_pos_UIAxes, 'Expected Load Position')
            xlabel(app.ref_pos_UIAxes, 'X')
            ylabel(app.ref_pos_UIAxes, 'Y')
            zlabel(app.ref_pos_UIAxes, 'Z')
            app.actual_pos_UIAxes = uiaxes(app.PositonTab);
            title(app.actual_pos_UIAxes, 'Actual Load Position')
            xlabel(app.actual_pos_UIAxes, 'X')
            ylabel(app.actual_pos_UIAxes, 'Y')
            zlabel(app.actual_pos_UIAxes, 'Z')
            app.VelocityTab = uitab(app.TabGroup4);
            app.VelocityTab.Title = 'Velocity';
            app.ref_vel_UIAxes = uiaxes(app.VelocityTab);
            title(app.ref_vel_UIAxes, 'Expected Load Velocity')
            xlabel(app.ref_vel_UIAxes, 'X')
            ylabel(app.ref_vel_UIAxes, 'Y')
            zlabel(app.ref_vel_UIAxes, 'Z')
            app.actual_vel_UIAxes = uiaxes(app.VelocityTab);
            title(app.actual_vel_UIAxes, 'Actual Load Velocity')
            xlabel(app.actual_vel_UIAxes, 'X')
            ylabel(app.actual_vel_UIAxes, 'Y')
            zlabel(app.actual_vel_UIAxes, 'Z')
            app.AccelerationTab = uitab(app.TabGroup4);
            app.AccelerationTab.Title = 'Acceleration';
            app.ref_acc_UIAxes = uiaxes(app.AccelerationTab);
            title(app.ref_acc_UIAxes, 'Expected Load Acceleration')
            xlabel(app.ref_acc_UIAxes, 'X')
            ylabel(app.ref_acc_UIAxes, 'Y')
            zlabel(app.ref_acc_UIAxes, 'Z')
            app.actual_acc_UIAxes = uiaxes(app.AccelerationTab);
            title(app.actual_acc_UIAxes, 'Actual Load Acceleration')
            xlabel(app.actual_acc_UIAxes, 'X')
            ylabel(app.actual_acc_UIAxes, 'Y')
            zlabel(app.actual_acc_UIAxes, 'Z')
            app.ErrorTab = uitab(app.TabGroup4);
            app.ErrorTab.Title = 'Error';
            app.pos_error_UIAxes = uiaxes(app.ErrorTab);
            title(app.pos_error_UIAxes, 'Position Error')
            xlabel(app.pos_error_UIAxes, 'X')
            ylabel(app.pos_error_UIAxes, 'Y')
            zlabel(app.pos_error_UIAxes, 'Z')
            app.vel_error_UIAxes = uiaxes(app.ErrorTab);
            title(app.vel_error_UIAxes, 'Velocity Error')
            xlabel(app.vel_error_UIAxes, 'X')
            ylabel(app.vel_error_UIAxes, 'Y')
            zlabel(app.vel_error_UIAxes, 'Z')
            app.acc_error_UIAxes = uiaxes(app.ErrorTab);
            title(app.acc_error_UIAxes, 'Acceleration Error')
            xlabel(app.acc_error_UIAxes, 'X')
            ylabel(app.acc_error_UIAxes, 'Y')
            zlabel(app.acc_error_UIAxes, 'Z')
            app.EnergyUsageTab = uitab(app.TabGroup2);
            app.EnergyUsageTab.Title = 'Energy Usage';
            app.actual_energy_UIAxes = uiaxes(app.EnergyUsageTab);
            title(app.actual_energy_UIAxes, 'Actual Energy Usage')
            xlabel(app.actual_energy_UIAxes, 'X')
            ylabel(app.actual_energy_UIAxes, 'Y')
            zlabel(app.actual_energy_UIAxes, 'Z')
            app.ActualEnergyUsageEditFieldLabel = uilabel(app.EnergyUsageTab);
            app.ActualEnergyUsageEditFieldLabel.Text = 'Actual Energy Usage';
            app.ActualEnergyUsageEditField = uieditfield(app.EnergyUsageTab, 'numeric');
            app.ActualEnergyUsageEditField.Editable = 'off';
            app.JLabel = uilabel(app.EnergyUsageTab);
            app.JLabel.Text = 'J';
            app.MotorCharacteristicsTab = uitab(app.TabGroup2);
            app.MotorCharacteristicsTab.Title = 'Motor Characteristics';
            app.TabGroup5 = uitabgroup(app.MotorCharacteristicsTab);
            app.CurrentandTorqueTab = uitab(app.TabGroup5);
            app.CurrentandTorqueTab.Title = 'Current and Torque';
            app.curr_UIAxes = uiaxes(app.CurrentandTorqueTab);
            title(app.curr_UIAxes, 'Motor Current')
            xlabel(app.curr_UIAxes, 'X')
            ylabel(app.curr_UIAxes, 'Y')
            zlabel(app.curr_UIAxes, 'Z')
            app.trq_UIAxes = uiaxes(app.CurrentandTorqueTab);
            title(app.trq_UIAxes, 'Motor Torque')
            xlabel(app.trq_UIAxes, 'X')
            ylabel(app.trq_UIAxes, 'Y')
            zlabel(app.trq_UIAxes, 'Z')
            app.VoltageTab = uitab(app.TabGroup5);
            app.VoltageTab.Title = 'Voltage';
            app.volt_UIAxes = uiaxes(app.VoltageTab);
            title(app.volt_UIAxes, 'Motor Voltage')
            xlabel(app.volt_UIAxes, 'X')
            ylabel(app.volt_UIAxes, 'Y')
            zlabel(app.volt_UIAxes, 'Z')
            app.RunCalculatorOptimiserButton = uibutton(app.UIFigure, 'push');
            app.RunCalculatorOptimiserButton.ButtonPushedFcn = createCallbackFcn(app, @RunCalculatorOptimiserButtonPushed, true);
            app.RunCalculatorOptimiserButton.Text = 'Run Calculator/Optimiser';
            app.AvailableLampLabel = uilabel(app.UIFigure);
            app.AvailableLampLabel.Text = 'Available';
            app.AvailableLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = motor_opti_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end